classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                % UI Figure
        TabGroup               matlab.ui.container.TabGroup    % Trainning,...
        TrainningTab           matlab.ui.container.Tab         % Trainning
        Label2                 matlab.ui.control.Label        
        FA_Layer3              matlab.ui.control.DropDown      % tansig, pu...
        FA_Layer2              matlab.ui.control.DropDown      % tansig, pu...
        Label3                 matlab.ui.control.Label         % Layer {1}
        Label4                 matlab.ui.control.Label         % Layer {2}
        Label5                 matlab.ui.control.Label         % Layer {3}
        Label6                 matlab.ui.control.Label         % Layer {4}
        CheckBoxActivateLayer3 matlab.ui.control.CheckBox     
        LabelDropDown          matlab.ui.control.Label         % Função de ...
        DP_FT                  matlab.ui.control.DropDown      % Levenberg-...
        LabelDropDown2         matlab.ui.control.Label         % Função de ...
        FA_Layer1              matlab.ui.control.DropDown      % tansig, pu...
        FA_Layer4              matlab.ui.control.DropDown      % tansig, pu...
        CheckBoxActivateLayer4 matlab.ui.control.CheckBox     
        CheckBoxActivateLayer2 matlab.ui.control.CheckBox     
        FA_OUTPUT              matlab.ui.control.DropDown      % tansig, pu...
        Label7                 matlab.ui.control.Label         % Layer {Out...
        nNeuroL2               matlab.ui.control.Spinner       % [1 10]
        nNeuroL3               matlab.ui.control.Spinner       % [1 10]
        nNeuroL4               matlab.ui.control.Spinner       % [1 10]
        LabelSpinner2          matlab.ui.control.Label         % Número de ...
        nNeuroL1               matlab.ui.control.Spinner       % [1 10]
        validationValue        matlab.ui.control.Spinner       % [0 35]
        testingValue           matlab.ui.control.Spinner       % [0 35]
        Label11                matlab.ui.control.Label         % Validação ...
        Label12                matlab.ui.control.Label         % Trainning
        Label13                matlab.ui.control.Label         % Validation
        Label14                matlab.ui.control.Label         % Testing
        TrainNet               matlab.ui.control.Button        % Treinar
        LabelSpinner           matlab.ui.control.Label         % Epochs
        epochsNumbers          matlab.ui.control.Spinner       % [100 1000]
        trainningValue         matlab.ui.control.Spinner       % [0 100]
        Label15                matlab.ui.control.Label         % Função de ...
        divFunction            matlab.ui.control.DropDown      % Divide the...
        Label16                matlab.ui.control.Label         %  
        Label17                matlab.ui.control.Label         %  
        LabelEditField         matlab.ui.control.Label         % Nome do Setup
        EditField              matlab.ui.control.EditField    
        ButtonGroup            matlab.ui.container.ButtonGroup % Dataset
        ToggleButton           matlab.ui.control.ToggleButton  % Formas_1
        ToggleButton2          matlab.ui.control.ToggleButton  % Formas_2
        ToggleButton3          matlab.ui.control.ToggleButton  % Formas_3
        ToggleButton4          matlab.ui.control.ToggleButton  % Option 1
        LoadingTab             matlab.ui.container.Tab         % Loading
        Label18                matlab.ui.control.Label        
        Label19                matlab.ui.control.Label         % Layer {1}
        Label20                matlab.ui.control.Label         % Layer {2}
        Label25                matlab.ui.control.Label         % Layer {Out...
        Label27                matlab.ui.control.Label         % Validação ...
        Label28                matlab.ui.control.Label         % Trainning
        Label29                matlab.ui.control.Label         % Validation
        Label30                matlab.ui.control.Label         % Testing
        Label32                matlab.ui.control.Label         % Função de ...
        Label34                matlab.ui.control.Label         %  
        Label35                matlab.ui.control.Label         % Função de ...
        Label36                matlab.ui.control.Label         % Função de ...
        Label37                matlab.ui.control.Label         % Layer {3}
        Label38                matlab.ui.control.Label         % Layer {4}
        Label39                matlab.ui.control.Label         % Numero de ...
        Label40                matlab.ui.control.Label         % Epochs:
        Label41                matlab.ui.control.Label        
        Label42                matlab.ui.control.Label        
        Label43                matlab.ui.control.Label        
        Label44                matlab.ui.control.Label        
        Label45                matlab.ui.control.Label        
        Label46                matlab.ui.control.Label        
        Label47                matlab.ui.control.Label        
        Label48                matlab.ui.control.Label        
        Label49                matlab.ui.control.Label        
        Label51                matlab.ui.control.Label        
        Label52                matlab.ui.control.Label        
        Label53                matlab.ui.control.Label        
        Label54                matlab.ui.control.Label        
        Label55                matlab.ui.control.Label        
        Label56                matlab.ui.control.Label        
        ListBoxAllSetups       matlab.ui.control.ListBox      
        RefreshButton          matlab.ui.control.Button        % Actualizar...
        RunSetupButton         matlab.ui.control.Button        % Run Setup
        TrainWithSetupCheckBox matlab.ui.control.CheckBox      % Treinar co...
        MyImages               matlab.ui.container.Tab         % My Images
        Label59                matlab.ui.control.Label        
        Label60                matlab.ui.control.Label         % Layer {1}
        Label61                matlab.ui.control.Label         % Layer {2}
        Label62                matlab.ui.control.Label         % Layer {Out...
        Label63                matlab.ui.control.Label         % Validação ...
        Label64                matlab.ui.control.Label         % Trainning
        Label65                matlab.ui.control.Label         % Validation
        Label66                matlab.ui.control.Label         % Testing
        Label67                matlab.ui.control.Label         % Função de ...
        Label68                matlab.ui.control.Label         %  
        Label69                matlab.ui.control.Label         % Função de ...
        Label70                matlab.ui.control.Label         % Função de ...
        Label71                matlab.ui.control.Label         % Layer {3}
        Label72                matlab.ui.control.Label         % Layer {4}
        Label73                matlab.ui.control.Label         % Numero de ...
        Label74                matlab.ui.control.Label         % Epochs:
        Label75                matlab.ui.control.Label        
        Label76                matlab.ui.control.Label        
        Label77                matlab.ui.control.Label        
        Label78                matlab.ui.control.Label        
        Label79                matlab.ui.control.Label        
        Label80                matlab.ui.control.Label        
        Label81                matlab.ui.control.Label        
        Label82                matlab.ui.control.Label        
        Label83                matlab.ui.control.Label        
        Label84                matlab.ui.control.Label        
        Label85                matlab.ui.control.Label        
        Label86                matlab.ui.control.Label        
        Label87                matlab.ui.control.Label        
        Label88                matlab.ui.control.Label        
        Label89                matlab.ui.control.Label        
        ListSetups1            matlab.ui.control.ListBox      
        RefreshBtListSetup     matlab.ui.control.Button        % Actualizar...
        RunSetupBt1            matlab.ui.control.Button        % Run Setup
        ImagesList             matlab.ui.control.ListBox      
        RefreshBtLImage        matlab.ui.control.Button        % Actualizar...
        Label90                matlab.ui.control.Label         % SETUPS 
        Label91                matlab.ui.control.Label         % IMAGENS
        PanelAlertName         matlab.ui.container.Panel       % Alerta
        Label9                 matlab.ui.control.Label         % Já existe ...
        Label10                matlab.ui.control.Label         % Deseja gra...
        cancelSave             matlab.ui.control.Button        % Não
        forceSave              matlab.ui.control.Button        % Sim
        PanelAlertError        matlab.ui.container.Panel       % Erro
        okError                matlab.ui.control.Button        % OK
        Label58                matlab.ui.control.Label         % ERRORRR
        PanelReulst            matlab.ui.container.Panel       % Resultados
        Button                 matlab.ui.control.Button        % OK
        Label92                matlab.ui.control.Label         % Precisão d...
        Label93                matlab.ui.control.Label         % Precisão G...
        Label94                matlab.ui.control.Label         % Label
        Label95                matlab.ui.control.Label         % Label
    end

    
    properties (Access = private)
        SaveSetup % Description
        SetupName
        ExcelFileName %nome do ficheiro exel criado
        SheetNumber % o numero da folha para onde tem de ser escrito a info
        SheetNumberLoad;
        DatasetChosen
        FileToLoad = -1;
        DoTrain
        ImageName
    end
    
    methods (Access = private)
        
        function results = changeTrainningValues(app)
            varValidationValue = app.validationValue.Value;
            varTestingValue = app.testingValue.Value;
            varTrainningValue =100-(varValidationValue+ varTestingValue);
            app.divFunction.Visible='on';
            app.Label15.Visible='on';
            app.Label17.Visible='on';
            app.trainningValue.Value= varTrainningValue;
        end
        
        function results = getActivationFunctions(app)
            
            if(app.CheckBoxActivateLayer4.Value)
                results = [str2num(app.FA_Layer1.Value), str2num(app.FA_Layer2.Value), str2num(app.FA_Layer3.Value), str2num(app.FA_Layer4.Value)];
            elseif(app.CheckBoxActivateLayer3.Value)
                results = [str2num(app.FA_Layer1.Value), str2num(app.FA_Layer2.Value), str2num(app.FA_Layer3.Value)];
            elseif(app.CheckBoxActivateLayer2.Value)
                results = [str2num(app.FA_Layer1.Value), str2num(app.FA_Layer2.Value)];
            else
                results = [str2num(app.FA_Layer1.Value)];
            end
            
        end
        
        function results = getNumberNeuro(app)
            if(app.CheckBoxActivateLayer4.Value)
                results = [app.nNeuroL1.Value, app.nNeuroL2.Value, app.nNeuroL3.Value, app.nNeuroL4.Value];
            elseif(app.CheckBoxActivateLayer3.Value)
                results = [app.nNeuroL1.Value, app.nNeuroL2.Value, app.nNeuroL3.Value];
            elseif(app.CheckBoxActivateLayer2.Value)
                results = [app.nNeuroL1.Value, app.nNeuroL2.Value];
            else
                results = [app.nNeuroL1.Value];
            end
            
        end
        
        function results = getDivFunc(app)
            
            if( strcmp( app.divFunction.Visible,'on'))
                results= app.divFunction.Value;
            else
                results=-1;
            end
            
        end
        
        function results = buildSetupInfo(app)
            
            list =dir ('**/../save/*.mat');
            listImg =dir ('**/../imgs/*.png');
            lenght = size(list);
            lenght= lenght(1);
            
            lenghtListImg = size(listImg);
            lenghtListImg= lenghtListImg(1);
            
            LboxNames{1}='CLEAR';
            LboxImagesNames{1}='CLEAR';
            for index= 1:lenght
                
                LboxNames{index+1} = list(index).name; %compile cell array of names.
            end
            
            for index2= 1:lenghtListImg
                
                LboxImagesNames{index2+1} = listImg(index2).name; %compile cell array of names.
            end
            
            app.ImagesList.Items=LboxImagesNames;
            app.ListBoxAllSetups.Items=LboxNames;
            app.ListSetups1.Items=LboxNames;
        end
        
        function results = verifyExcelFiles(app)
            list =dir ('**/../output/*.xlsx');
            lenght = size(list);
            lenght= lenght(1);
            
            filename=strcat('excel_',int2str(lenght+1));
            NULL = {' ',' '};
            xlsxfilename = strcat('./output/',filename,'.xlsx');
            xlswrite(xlsxfilename,NULL);
            app.ExcelFileName = xlsxfilename;
            app.SheetNumberLoad = 0;
            app.SheetNumber=0;
        end
        
        function results = resolveCallback(app,code, setup,testAccuracy, globalAccuracy)
            if(code==409 )
                app.SaveSetup=setup;
                app.PanelAlertName.Position = [236 150 380 104];
                app.PanelAlertName.Visible='on';
                app.buildSetupInfo();
            elseif(code==200)
                app.PanelReulst.Position = [236 150 390 175];
                app.PanelReulst.Visible='on';
                if(~strcmp(testAccuracy,'-'))
                    
                    app.Label94.Text=strcat(num2str(testAccuracy),'%');
                else
                    app.Label94.Text='-';
                end
                app.Label95.Text=strcat(num2str(globalAccuracy),'%');
                app.buildSetupInfo();
            else(code==500)
                %apresentar erro painel
                app.Label58.Text=setup;
                app.PanelAlertError.Position =  [236 150 380 104];
                app.PanelAlertError.Visible='on';
            end
        end
        
        function results = clearLabelsSetup(app)
            app.Label41.Text = ' ';
            app.Label42.Text = ' ';
            app.Label43.Text = ' ';
            app.Label44.Text = ' ';
            
            app.Label46.Text = ' ';
            app.Label47.Text =' ';
            app.Label48.Text =' ';
            app.Label49.Text = ' ';
            
            app.Label56.Text = ' ';
            app.Label45.Text = ' ';
            
            app.Label51.Text = ' ';
            
            app.Label55.Text =   ' ';
            app.Label52.Text =  ' ';
            app.Label53.Text =   ' ';
            app.Label54.Text = ' ';
            
            app.Label75.Text = ' ';
            app.Label76.Text = ' ';
            app.Label77.Text = ' ';
            app.Label78.Text = ' ';
            
            app.Label80.Text = ' ';
            app.Label81.Text = ' ';
            app.Label82.Text = ' ';
            app.Label83.Text = ' ';


            app.Label89.Text = ' ';
            app.Label79.Text = ' ';
            
            app.Label84.Text = ' ';
            app.Label88.Text =  ' ';
            app.Label85.Text =  ' ';
            app.Label86.Text =  ' ';
            app.Label87.Text = ' ';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SaveFolder= './save';
            if ~exist(SaveFolder, 'dir')
                mkdir(SaveFolder)
            end
            OutputFolder= './output';
            if ~exist(OutputFolder, 'dir')
                mkdir(OutputFolder)
            end
            ImagesFolder='./imgs';
            if ~exist(ImagesFolder, 'dir')
                mkdir(ImagesFolder)
            end
              ImagesOutputFolder='./outputImg';
            if ~exist(ImagesOutputFolder, 'dir')
                mkdir(ImagesOutputFolder)
            end
            
            app.ImageName='CLEAR';
            app.SetupName='CLEAR';
            app.verifyExcelFiles();
            app.Label16.Text = '(trainlm)';
            app.PanelAlertName.Visible='off';
            app.Label17.Text = '(dividerand)';
            app.buildSetupInfo();
            
            
        end

        % TrainNet button pushed function
        function TrainNetButtonPushed(app)
            if( strcmp(strtrim(app.EditField.Value),'') )
                
            else
                
                activationFunctions= app.getActivationFunctions();
                numberNeuro=app.getNumberNeuro();
                divFunc= app.getDivFunc();
                epochsNumber = app.epochsNumbers.Value;
                trainningFunction = app.DP_FT.Value;
                activationFuntionOutput = app.FA_OUTPUT.Value;
                trainningWeights= [app.trainningValue.Value, app.validationValue.Value, app.testingValue.Value];
                setupName=app.EditField.Value;
                app.SheetNumber = app.SheetNumber+1;
                app.ButtonGroupSelectionChanged();
                [code, setup, testAccuracy, globalAccuracy] = trainningNetwork( activationFunctions, str2num(activationFuntionOutput), trainningFunction, epochsNumber, numberNeuro, trainningWeights, divFunc, setupName, app.ExcelFileName, app.SheetNumber,app.DatasetChosen,1,'0');
                app.resolveCallback(code, setup, testAccuracy, globalAccuracy)
                
            end


        end

        % DP_FT value changed function
        function DP_FTValueChanged(app, event)
            value = app.DP_FT.Value;
            
            switch(value)
                case '3'
                    app.Label16.Text = '(trainlm)';
                case '4'
                    app.Label16.Text = '(trainbfg)';
                case '5'
                    app.Label16.Text = '(trainrp)';
                case '6'
                    app.Label16.Text = '(trainscg)';
                case '7'
                    app.Label16.Text = '(traincgb)';
                case '8'
                    app.Label16.Text = '(traincgf)';
                case '9'
                    app.Label16.Text = '(traincgp)';
                case '10'
                    app.Label16.Text = '(trainoss)';
                case '11'
                    app.Label16.Text = '(traingdx)';
            end
        end

        % CheckBoxActivateLayer3 value changed function
        function CheckBoxActivateLayer3ValueChanged(app)
            value = app.CheckBoxActivateLayer3.Value;
            if(value)
                app.FA_Layer3.Enable = 'on';
                app.FA_Layer4.Visible = 'on';
                app.CheckBoxActivateLayer4.Visible = 'on';
                app.Label6.Visible = 'on';
                app.nNeuroL3.Enable='on';
                app.nNeuroL4.Visible='on';
            else
                app.FA_Layer3.Enable = 'off';
                app.FA_Layer4.Visible = 'off';
                app.CheckBoxActivateLayer4.Visible = 'off';
                app.Label6.Visible = 'off';
                app.nNeuroL3.Enable='off';
                app.nNeuroL4.Visible='off';
            end
        end

        % forceSave button pushed function
        function forceSaveButtonPushed(app)
            filename = strcat('./save/',strtrim(app.EditField.Value),'.mat');
            setup=app.SaveSetup;
            disp(setup)
            disp(filename);
            movefile(setup,filename)        %# Rename the file
            writeExcellFile(strtrim(app.EditField.Value),filename,app.SheetNumber, app.ExcelFileName);
            app.PanelAlertName.Position = [81 -268 380 104];
            app.PanelAlertName.Visible='off';
        end

        % cancelSave button pushed function
        function cancelSaveButtonPushed(app)
            delete './save/tmp.mat';
            app.PanelAlertName.Position = [81 -268 380 104];
            app.PanelAlertName.Visible='off';
        end

        % CheckBoxActivateLayer2 value changed function
        function CheckBoxActivateLayer2ValueChanged(app)
            value = app.CheckBoxActivateLayer2.Value;
            
            
            if(value)
                app.FA_Layer2.Enable = 'on';
                app.FA_Layer3.Visible = 'on';
                app.CheckBoxActivateLayer3.Visible = 'on';
                app.Label5.Visible = 'on';
                app.nNeuroL2.Enable='on';
                app.nNeuroL3.Visible='on';
            else
                app.FA_Layer2.Enable = 'off';
                app.FA_Layer3.Visible = 'off';
                app.CheckBoxActivateLayer3.Visible = 'off';
                app.Label5.Visible = 'off';
                app.nNeuroL2.Enable='off';
                app.nNeuroL3.Visible='off';
            end
        end

        % CheckBoxActivateLayer4 value changed function
        function CheckBoxActivateLayer4ValueChanged(app)
            value = app.CheckBoxActivateLayer4.Value;
            if(value)
                app.FA_Layer4.Enable = 'on';
                app.nNeuroL4.Enable='on';
            else
                app.FA_Layer4.Enable = 'off';
                app.nNeuroL4.Enable='off';
            end
        end

        % validationValue value changed function
        function validationValueValueChanged(app)
            
            varValidationValue = app.validationValue.Value;
            varTestingValue = app.testingValue.Value;
            if(varValidationValue==0)
                app.trainningValue.Value= 100;
                app.validationValue.Value=0;
                app.testingValue.Value=0;
                app.Label15.Visible='off';
                app.Label17.Visible='off';
                app.divFunction.Visible='off';
            elseif(varTestingValue==0&&varValidationValue>=5)
                app.testingValue.Value=5;
                app.changeTrainningValues();
            else
                app.changeTrainningValues();
            end
            
            
        end

        % testingValue value changed function
        function testingValueValueChanged(app)
            
            varTestingValue = app.testingValue.Value;
            varValidationValue = app.validationValue.Value;
            if(varTestingValue==0)
                app.validationValue.Value=0;
                app.testingValue.Value=0;
                app.trainningValue.Value= 100;
                app.Label17.Visible='off';
                app.Label15.Visible='off';
                app.divFunction.Visible='off';
            elseif(varValidationValue==0&&varTestingValue>=5)
                app.validationValue.Value=5;
                app.changeTrainningValues();
            else
                app.changeTrainningValues();
            end
        end

        % divFunction value changed function
        function divFunctionValueChanged(app, event)
            value = app.divFunction.Value;
            switch(value)
                case '12'
                    app.Label17.Text = '(dividerand)';
                case '13'
                    app.Label17.Text = '(divideblock)';
                case '14'
                    app.Label17.Text = '(divideint)';
                case '15'
                    app.Label17.Text = '(divideind)';
                    
            end
        end

        % ListBoxAllSetups value changed function
        function ListBoxAllSetupsValueChanged(app)
            value = app.ListBoxAllSetups.Value;
            app.clearLabelsSetup();
            app.SetupName = value;
            if(~strcmp(value,'CLEAR'))
                
                pathFile= strcat('./save/',value);
                app.FileToLoad= pathFile;
                setup = load(pathFile);
                disp(setup)
                sizeFA = size(setup.activationFunctions);
                sizeFA = sizeFA(2);
                if(sizeFA==4)
                    app.Label41.Text = convertConstants(setup.activationFunctions(1));
                    app.Label42.Text = convertConstants(setup.activationFunctions(2));
                    app.Label43.Text = convertConstants(setup.activationFunctions(3));
                    app.Label44.Text = convertConstants(setup.activationFunctions(4));
                    
                    app.Label46.Text = num2str(setup.neuroNumber(1));
                    app.Label47.Text = num2str(setup.neuroNumber(2));
                    app.Label48.Text = num2str(setup.neuroNumber(3));
                    app.Label49.Text = num2str(setup.neuroNumber(4));
                elseif(sizeFA==3)
                    app.Label41.Text = convertConstants(setup.activationFunctions(1));
                    app.Label42.Text = convertConstants(setup.activationFunctions(2));
                    app.Label43.Text = convertConstants(setup.activationFunctions(3));
                    
                    app.Label46.Text = num2str(setup.neuroNumber(1));
                    app.Label47.Text = num2str(setup.neuroNumber(2));
                    app.Label48.Text = num2str(setup.neuroNumber(3));
                elseif(sizeFA==2)
                    app.Label41.Text = convertConstants(setup.activationFunctions(1));
                    app.Label42.Text = convertConstants(setup.activationFunctions(2));
                    
                    app.Label46.Text = num2str(setup.neuroNumber(1));
                    app.Label47.Text = num2str(setup.neuroNumber(2));
                else
                    app.Label41.Text = convertConstants(setup.activationFunctions(1));
                    app.Label46.Text = num2str(setup.neuroNumber(1));
                end
                app.Label56.Text = convertConstants(setup.trainningFunction);
                app.Label45.Text = convertConstants(setup.activationFuntionOutput);
                
                app.Label51.Text = num2str(setup.epochsNumber);
                if(setup.divFunc~= -1)
                    app.Label55.Text =   convertConstants(setup.divFunc);
                    app.Label52.Text =  strcat(num2str(setup.trainningWeights(1)),'%');
                    app.Label53.Text =  strcat(num2str(setup.trainningWeights(2)),'%');
                    app.Label54.Text = strcat(num2str(setup.trainningWeights(3)),'%');
                    
                end
                
            else
                app.clearLabelsSetup();
            end
        end

        % LoadingTab size change function
        function LoadingTabSizeChanged(app)
            position = app.LoadingTab.Position;
            
        end

        % okError button pushed function
        function okErrorButtonPushed(app)
            app.PanelAlertError.Visible='off';
            app.PanelAlertError.Position = [780 -183 380 104];
            
        end

        % ButtonGroup selection change function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.DatasetChosen = selectedButton.Text;
        end

        % RefreshButton button pushed function
        function RefreshButtonButtonPushed(app)
            app.buildSetupInfo();
        end

        % RunSetupButton button pushed function
        function RunSetupButtonButtonPushed(app)
            if(app.FileToLoad==-1)
                app.resolveCallback(500, 'Selecione um ficheiro');
            else
                app.TrainWithSetupCheckBoxValueChanged();
                app.SheetNumberLoad = app.SheetNumberLoad+1;
                
                [code, setup,testAccuracy, globalAccuracy] = trainningNetwork( ' ', ' ', ' ', ' ', ' ', ' ', ' ', app.SetupName, app.ExcelFileName, app.SheetNumberLoad,'Formas_3',app.DoTrain,app.FileToLoad);
                app.resolveCallback(code,setup,testAccuracy, globalAccuracy);
            end
            
        end

        % TrainWithSetupCheckBox value changed function
        function TrainWithSetupCheckBoxValueChanged(app)
            value = app.TrainWithSetupCheckBox.Value;
            if(value)
                app.DoTrain = 1;
            else
                app.DoTrain = 0;
            end
        end

        % RefreshBtListSetup button pushed function
        function RefreshBtListSetupButtonPushed(app)
            app.buildSetupInfo();
        end

        % RefreshBtLImage button pushed function
        function RefreshBtLImageButtonPushed(app)
            app.buildSetupInfo();
        end

        % ListSetups1 value changed function
        function ListSetups1ValueChanged(app)
            value = app.ListSetups1.Value;
                            app.clearLabelsSetup();
            app.SetupName = value;
            if(~strcmp(value,'CLEAR'))
                
                pathFile= strcat('./save/',value);
                app.FileToLoad= pathFile;
                setup = load(pathFile);
                disp(setup)
                sizeFA = size(setup.activationFunctions);
                sizeFA = sizeFA(2);
                if(sizeFA==4)
                    app.Label75.Text = convertConstants(setup.activationFunctions(1));
                    app.Label76.Text = convertConstants(setup.activationFunctions(2));
                    app.Label77.Text = convertConstants(setup.activationFunctions(3));
                    app.Label78.Text = convertConstants(setup.activationFunctions(4));
                    
                    app.Label80.Text = num2str(setup.neuroNumber(1));
                    app.Label81.Text = num2str(setup.neuroNumber(2));
                    app.Label82.Text = num2str(setup.neuroNumber(3));
                    app.Label83.Text = num2str(setup.neuroNumber(4));
                elseif(sizeFA==3)
                    app.Label75.Text = convertConstants(setup.activationFunctions(1));
                    app.Label76.Text = convertConstants(setup.activationFunctions(2));
                    app.Label77.Text = convertConstants(setup.activationFunctions(3));
                    
                    app.Label80.Text = num2str(setup.neuroNumber(1));
                    app.Label81.Text = num2str(setup.neuroNumber(2));
                    app.Label82.Text = num2str(setup.neuroNumber(3));
                elseif(sizeFA==2)
                    app.Label75.Text = convertConstants(setup.activationFunctions(1));
                    app.Label76.Text = convertConstants(setup.activationFunctions(2));
                    
                    app.Label80.Text = num2str(setup.neuroNumber(1));
                    app.Label81.Text = num2str(setup.neuroNumber(2));
                else
                    app.Label75.Text = convertConstants(setup.activationFunctions(1));
                    app.Label80.Text = num2str(setup.neuroNumber(1));
                end
                app.Label89.Text = convertConstants(setup.trainningFunction);
                app.Label79.Text = convertConstants(setup.activationFuntionOutput);
                
                app.Label84.Text = num2str(setup.epochsNumber);
                if(setup.divFunc~= -1)
                    app.Label88.Text =   convertConstants(setup.divFunc);
                    app.Label85.Text =  strcat(num2str(setup.trainningWeights(1)),'%');
                    app.Label86.Text =  strcat(num2str(setup.trainningWeights(2)),'%');
                    app.Label87.Text = strcat(num2str(setup.trainningWeights(3)),'%');
                    
                end
            else
                app.clearLabelsSetup();
            end
        end

        % ImagesList value changed function
        function ImagesListValueChanged(app)
            value = app.ImagesList.Value;
            app.ImageName = value;
            if(~strcmp(value,'CLEAR'))
                
                imagePath=strcat('./Formas_1/',value);
                app.ImageName = imagePath;
                image = imread(imagePath);
                imshow(image)
            end
        end

        % RunSetupBt1 button pushed function
        function RunSetupBt1ButtonPushed(app)
            
            if(strcmp(app.SetupName,'CLEAR'))
                
                app.resolveCallback(500, 'Selecione um SETUP e uma IMAGEM');
            else
                if( strcmp(app.ImageName,'CLEAR'))
                    app.resolveCallback(500, 'Selecione um SETUP e uma IMAGEM');
                else
                    %TODO CALL FUNC
                    imageRecognize( app.ImageName, app.SetupName )
                    
                    
                end
                
            end
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            
            app.PanelReulst.Visible='off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.TrainningTab = uitab(app.TabGroup);
            app.TrainningTab.Units = 'pixels';
            app.TrainningTab.Title = 'Trainning';
            app.Label2 = uilabel(app.TrainningTab);
            app.Label2.Text = '';
            app.FA_Layer3 = uidropdown(app.TrainningTab);
            app.FA_Layer3.Items = {'tansig', 'purelin', 'logsig'};
            app.FA_Layer3.ItemsData = {'0', '1', '2'};
            app.FA_Layer3.Enable = 'off';
            app.FA_Layer3.Visible = 'off';
            app.FA_Layer3.Value = '0';
            app.FA_Layer2 = uidropdown(app.TrainningTab);
            app.FA_Layer2.Items = {'tansig', 'purelin', 'logsig'};
            app.FA_Layer2.ItemsData = {'0', '1', '2'};
            app.FA_Layer2.Enable = 'off';
            app.FA_Layer2.Value = '0';
            app.Label3 = uilabel(app.TrainningTab);
            app.Label3.Text = 'Layer {1}';
            app.Label4 = uilabel(app.TrainningTab);
            app.Label4.Text = 'Layer {2}';
            app.Label5 = uilabel(app.TrainningTab);
            app.Label5.Visible = 'off';
            app.Label5.Text = 'Layer {3}';
            app.Label6 = uilabel(app.TrainningTab);
            app.Label6.Visible = 'off';
            app.Label6.Text = 'Layer {4}';
            app.CheckBoxActivateLayer3 = uicheckbox(app.TrainningTab);
            app.CheckBoxActivateLayer3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxActivateLayer3ValueChanged);
            app.CheckBoxActivateLayer3.Visible = 'off';
            app.CheckBoxActivateLayer3.Text = '';
            app.LabelDropDown = uilabel(app.TrainningTab);
            app.LabelDropDown.Text = 'Função de treino';
            app.DP_FT = uidropdown(app.TrainningTab);
            app.DP_FT.Items = {'Levenberg-Marquardt', 'BFGS Quasi-Newton', 'Resilient Backpropagation', 'Scaled Conjugate Gradient', 'Conjugate Gradient with Powell/Beale Restarts', 'Fletcher-Powell Conjugate Gradient', 'Polak-Ribiére Conjugate Gradient', 'One Step Secant', 'Variable Learning Rate Backpropagation'};
            app.DP_FT.ItemsData = {'3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DP_FT.ValueChangedFcn = createCallbackFcn(app, @DP_FTValueChanged, true);
            app.DP_FT.Value = '3';
            app.LabelDropDown2 = uilabel(app.TrainningTab);
            app.LabelDropDown2.Text = 'Função de activação';
            app.FA_Layer1 = uidropdown(app.TrainningTab);
            app.FA_Layer1.Items = {'tansig', 'purelin', 'logsig'};
            app.FA_Layer1.ItemsData = {'0', '1', '2'};
            app.FA_Layer1.Value = '0';
            app.FA_Layer4 = uidropdown(app.TrainningTab);
            app.FA_Layer4.Items = {'tansig', 'purelin', 'logsig'};
            app.FA_Layer4.ItemsData = {'0', '1', '2'};
            app.FA_Layer4.Enable = 'off';
            app.FA_Layer4.Visible = 'off';
            app.FA_Layer4.Value = '0';
            app.CheckBoxActivateLayer4 = uicheckbox(app.TrainningTab);
            app.CheckBoxActivateLayer4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxActivateLayer4ValueChanged);
            app.CheckBoxActivateLayer4.Visible = 'off';
            app.CheckBoxActivateLayer4.Text = '';
            app.CheckBoxActivateLayer2 = uicheckbox(app.TrainningTab);
            app.CheckBoxActivateLayer2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxActivateLayer2ValueChanged);
            app.CheckBoxActivateLayer2.Text = '';
            app.FA_OUTPUT = uidropdown(app.TrainningTab);
            app.FA_OUTPUT.Items = {'tansig', 'purelin', 'logsig'};
            app.FA_OUTPUT.ItemsData = {'0', '1', '2'};
            app.FA_OUTPUT.Value = '0';
            app.Label7 = uilabel(app.TrainningTab);
            app.Label7.Text = 'Layer {Output}';
            app.nNeuroL2 = uispinner(app.TrainningTab);
            app.nNeuroL2.Limits = [1 10];
            app.nNeuroL2.ValueDisplayFormat = '%.0f';
            app.nNeuroL2.Enable = 'off';
            app.nNeuroL2.Value = 10;
            app.nNeuroL3 = uispinner(app.TrainningTab);
            app.nNeuroL3.Limits = [1 10];
            app.nNeuroL3.ValueDisplayFormat = '%.0f';
            app.nNeuroL3.Enable = 'off';
            app.nNeuroL3.Visible = 'off';
            app.nNeuroL3.Value = 10;
            app.nNeuroL4 = uispinner(app.TrainningTab);
            app.nNeuroL4.Limits = [1 10];
            app.nNeuroL4.ValueDisplayFormat = '%.0f';
            app.nNeuroL4.Enable = 'off';
            app.nNeuroL4.Visible = 'off';
            app.nNeuroL4.Value = 10;
            app.LabelSpinner2 = uilabel(app.TrainningTab);
            app.LabelSpinner2.Text = 'Número de neurónios';
            app.nNeuroL1 = uispinner(app.TrainningTab);
            app.nNeuroL1.Limits = [1 10];
            app.nNeuroL1.ValueDisplayFormat = '%.0f';
            app.nNeuroL1.Value = 10;
            app.validationValue = uispinner(app.TrainningTab);
            app.validationValue.Step = 5;
            app.validationValue.ValueChangedFcn = createCallbackFcn(app, @validationValueValueChanged);
            app.validationValue.Limits = [0 35];
            app.validationValue.ValueDisplayFormat = '%.0f';
            app.testingValue = uispinner(app.TrainningTab);
            app.testingValue.Step = 5;
            app.testingValue.ValueChangedFcn = createCallbackFcn(app, @testingValueValueChanged);
            app.testingValue.Limits = [0 35];
            app.testingValue.ValueDisplayFormat = '%.0f';
            app.Label11 = uilabel(app.TrainningTab);
            app.Label11.Text = 'Validação e Test Data (Samples)';
            app.Label12 = uilabel(app.TrainningTab);
            app.Label12.Text = 'Trainning';
            app.Label13 = uilabel(app.TrainningTab);
            app.Label13.Text = 'Validation';
            app.Label14 = uilabel(app.TrainningTab);
            app.Label14.Text = 'Testing';
            app.TrainNet = uibutton(app.TrainningTab, 'push');
            app.TrainNet.ButtonPushedFcn = createCallbackFcn(app, @TrainNetButtonPushed);
            app.TrainNet.Text = 'Treinar';
            app.LabelSpinner = uilabel(app.TrainningTab);
            app.LabelSpinner.Text = 'Epochs';
            app.epochsNumbers = uispinner(app.TrainningTab);
            app.epochsNumbers.Limits = [100 1000];
            app.epochsNumbers.Value = 100;
            app.trainningValue = uispinner(app.TrainningTab);
            app.trainningValue.Step = 5;
            app.trainningValue.Limits = [0 100];
            app.trainningValue.ValueDisplayFormat = '%.0f';
            app.trainningValue.Enable = 'off';
            app.trainningValue.Value = 100;
            app.Label15 = uilabel(app.TrainningTab);
            app.Label15.Visible = 'off';
            app.Label15.Text = 'Função de Divisão de Samples';
            app.divFunction = uidropdown(app.TrainningTab);
            app.divFunction.Items = {'Divide the data randomly (default)', 'Divide the data using an interleaved selection', 'Divide the data by index'};
            app.divFunction.ItemsData = {'12', '14', '15'};
            app.divFunction.ValueChangedFcn = createCallbackFcn(app, @divFunctionValueChanged, true);
            app.divFunction.Visible = 'off';
            app.divFunction.Value = '12';
            app.Label16 = uilabel(app.TrainningTab);
            app.Label16.Text = ' ';
            app.Label17 = uilabel(app.TrainningTab);
            app.Label17.Visible = 'off';
            app.Label17.Text = ' ';
            app.LabelEditField = uilabel(app.TrainningTab);
            app.LabelEditField.Text = 'Nome do Setup';
            app.EditField = uieditfield(app.TrainningTab, 'text');
            app.ButtonGroup = uibuttongroup(app.TrainningTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Dataset';
            app.ButtonGroup.Units = 'pixels';
            app.ToggleButton = uitogglebutton(app.ButtonGroup);
            app.ToggleButton.Text = 'Formas_1';
            app.ToggleButton.Value = true;
            app.ToggleButton2 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton2.Text = 'Formas_2';
            app.ToggleButton3 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton3.Text = 'Formas_3';
            app.ToggleButton4 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton4.Text = 'Option 1';
            app.LoadingTab = uitab(app.TabGroup);
            app.LoadingTab.Units = 'pixels';
            app.LoadingTab.SizeChangedFcn = createCallbackFcn(app, @LoadingTabSizeChanged);
            app.LoadingTab.Title = 'Loading';
            app.Label18 = uilabel(app.LoadingTab);
            app.Label18.Text = '';
            app.Label19 = uilabel(app.LoadingTab);
            app.Label19.Text = 'Layer {1}';
            app.Label20 = uilabel(app.LoadingTab);
            app.Label20.Text = 'Layer {2}';
            app.Label25 = uilabel(app.LoadingTab);
            app.Label25.Text = 'Layer {Output}';
            app.Label27 = uilabel(app.LoadingTab);
            app.Label27.Text = 'Validação e Test Data (Samples):';
            app.Label28 = uilabel(app.LoadingTab);
            app.Label28.Text = 'Trainning';
            app.Label29 = uilabel(app.LoadingTab);
            app.Label29.Text = 'Validation';
            app.Label30 = uilabel(app.LoadingTab);
            app.Label30.Text = 'Testing';
            app.Label32 = uilabel(app.LoadingTab);
            app.Label32.Text = 'Função de Divisão de Samples:';
            app.Label34 = uilabel(app.LoadingTab);
            app.Label34.Visible = 'off';
            app.Label34.Text = ' ';
            app.Label35 = uilabel(app.LoadingTab);
            app.Label35.Text = 'Função de treino:';
            app.Label36 = uilabel(app.LoadingTab);
            app.Label36.Text = 'Função de activação: ';
            app.Label37 = uilabel(app.LoadingTab);
            app.Label37.Text = 'Layer {3}';
            app.Label38 = uilabel(app.LoadingTab);
            app.Label38.Text = 'Layer {4}';
            app.Label39 = uilabel(app.LoadingTab);
            app.Label39.Text = 'Numero de neuronios: ';
            app.Label40 = uilabel(app.LoadingTab);
            app.Label40.Text = 'Epochs:';
            app.Label41 = uilabel(app.LoadingTab);
            app.Label41.Text = '';
            app.Label42 = uilabel(app.LoadingTab);
            app.Label42.Text = '';
            app.Label43 = uilabel(app.LoadingTab);
            app.Label43.Text = '';
            app.Label44 = uilabel(app.LoadingTab);
            app.Label44.Text = '';
            app.Label45 = uilabel(app.LoadingTab);
            app.Label45.Text = '';
            app.Label46 = uilabel(app.LoadingTab);
            app.Label46.Text = '';
            app.Label47 = uilabel(app.LoadingTab);
            app.Label47.Text = '';
            app.Label48 = uilabel(app.LoadingTab);
            app.Label48.Text = '';
            app.Label49 = uilabel(app.LoadingTab);
            app.Label49.Text = '';
            app.Label51 = uilabel(app.LoadingTab);
            app.Label51.Text = '';
            app.Label52 = uilabel(app.LoadingTab);
            app.Label52.Text = '';
            app.Label53 = uilabel(app.LoadingTab);
            app.Label53.Text = '';
            app.Label54 = uilabel(app.LoadingTab);
            app.Label54.Text = '';
            app.Label55 = uilabel(app.LoadingTab);
            app.Label55.Text = '';
            app.Label56 = uilabel(app.LoadingTab);
            app.Label56.Text = '';
            app.ListBoxAllSetups = uilistbox(app.LoadingTab);
            app.ListBoxAllSetups.Items = {};
            app.ListBoxAllSetups.ValueChangedFcn = createCallbackFcn(app, @ListBoxAllSetupsValueChanged);
            app.ListBoxAllSetups.Value = {};
            app.RefreshButton = uibutton(app.LoadingTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonButtonPushed);
            app.RefreshButton.Text = 'Actualizar Lista';
            app.RunSetupButton = uibutton(app.LoadingTab, 'push');
            app.RunSetupButton.ButtonPushedFcn = createCallbackFcn(app, @RunSetupButtonButtonPushed);
            app.RunSetupButton.Text = 'Run Setup';
            app.TrainWithSetupCheckBox = uicheckbox(app.LoadingTab);
            app.TrainWithSetupCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainWithSetupCheckBoxValueChanged);
            app.TrainWithSetupCheckBox.Text = 'Treinar com setup';
            app.MyImages = uitab(app.TabGroup);
            app.MyImages.Units = 'pixels';
            app.MyImages.Title = 'My Images';
            app.Label59 = uilabel(app.MyImages);
            app.Label59.Text = '';
            app.Label60 = uilabel(app.MyImages);
            app.Label60.Text = 'Layer {1}';
            app.Label61 = uilabel(app.MyImages);
            app.Label61.Text = 'Layer {2}';
            app.Label62 = uilabel(app.MyImages);
            app.Label62.Text = 'Layer {Output}';
            app.Label63 = uilabel(app.MyImages);
            app.Label63.Text = 'Validação e Test Data (Samples):';
            app.Label64 = uilabel(app.MyImages);
            app.Label64.Text = 'Trainning';
            app.Label65 = uilabel(app.MyImages);
            app.Label65.Text = 'Validation';
            app.Label66 = uilabel(app.MyImages);
            app.Label66.Text = 'Testing';
            app.Label67 = uilabel(app.MyImages);
            app.Label67.Text = 'Função de Divisão de Samples:';
            app.Label68 = uilabel(app.MyImages);
            app.Label68.Visible = 'off';
            app.Label68.Text = ' ';
            app.Label69 = uilabel(app.MyImages);
            app.Label69.Text = 'Função de treino:';
            app.Label70 = uilabel(app.MyImages);
            app.Label70.Text = 'Função de activação: ';
            app.Label71 = uilabel(app.MyImages);
            app.Label71.Text = 'Layer {3}';
            app.Label72 = uilabel(app.MyImages);
            app.Label72.Text = 'Layer {4}';
            app.Label73 = uilabel(app.MyImages);
            app.Label73.Text = 'Numero de neuronios: ';
            app.Label74 = uilabel(app.MyImages);
            app.Label74.Text = 'Epochs:';
            app.Label75 = uilabel(app.MyImages);
            app.Label75.Text = '';
            app.Label76 = uilabel(app.MyImages);
            app.Label76.Text = '';
            app.Label77 = uilabel(app.MyImages);
            app.Label77.Text = '';
            app.Label78 = uilabel(app.MyImages);
            app.Label78.Text = '';
            app.Label79 = uilabel(app.MyImages);
            app.Label79.Text = '';
            app.Label80 = uilabel(app.MyImages);
            app.Label80.Text = '';
            app.Label81 = uilabel(app.MyImages);
            app.Label81.Text = '';
            app.Label82 = uilabel(app.MyImages);
            app.Label82.Text = '';
            app.Label83 = uilabel(app.MyImages);
            app.Label83.Text = '';
            app.Label84 = uilabel(app.MyImages);
            app.Label84.Text = '';
            app.Label85 = uilabel(app.MyImages);
            app.Label85.Text = '';
            app.Label86 = uilabel(app.MyImages);
            app.Label86.Text = '';
            app.Label87 = uilabel(app.MyImages);
            app.Label87.Text = '';
            app.Label88 = uilabel(app.MyImages);
            app.Label88.Text = '';
            app.Label89 = uilabel(app.MyImages);
            app.Label89.Text = '';
            app.ListSetups1 = uilistbox(app.MyImages);
            app.ListSetups1.Items = {};
            app.ListSetups1.ValueChangedFcn = createCallbackFcn(app, @ListSetups1ValueChanged);
            app.ListSetups1.Value = {};
            app.RefreshBtListSetup = uibutton(app.MyImages, 'push');
            app.RefreshBtListSetup.ButtonPushedFcn = createCallbackFcn(app, @RefreshBtListSetupButtonPushed);
            app.RefreshBtListSetup.Text = 'Actualizar Lista';
            app.RunSetupBt1 = uibutton(app.MyImages, 'push');
            app.RunSetupBt1.ButtonPushedFcn = createCallbackFcn(app, @RunSetupBt1ButtonPushed);
            app.RunSetupBt1.Text = 'Run Setup';
            app.ImagesList = uilistbox(app.MyImages);
            app.ImagesList.Items = {};
            app.ImagesList.ValueChangedFcn = createCallbackFcn(app, @ImagesListValueChanged);
            app.ImagesList.Value = {};
            app.RefreshBtLImage = uibutton(app.MyImages, 'push');
            app.RefreshBtLImage.ButtonPushedFcn = createCallbackFcn(app, @RefreshBtLImageButtonPushed);
            app.RefreshBtLImage.Text = 'Actualizar Lista';
            app.Label90 = uilabel(app.MyImages);
            app.Label90.Text = 'SETUPS ';
            app.Label91 = uilabel(app.MyImages);
            app.Label91.Text = 'IMAGENS';
            app.PanelAlertName = uipanel(app.UIFigure);
            app.PanelAlertName.Title = 'Alerta';
            app.PanelAlertName.Units = 'pixels';
            app.Label9 = uilabel(app.PanelAlertName);
            app.Label9.Text = 'Já existe uma configuração com este nome gravada';
            app.Label10 = uilabel(app.PanelAlertName);
            app.Label10.Text = 'Deseja gravar por cima?';
            app.cancelSave = uibutton(app.PanelAlertName, 'push');
            app.cancelSave.ButtonPushedFcn = createCallbackFcn(app, @cancelSaveButtonPushed);
            app.cancelSave.Text = 'Não';
            app.forceSave = uibutton(app.PanelAlertName, 'push');
            app.forceSave.ButtonPushedFcn = createCallbackFcn(app, @forceSaveButtonPushed);
            app.forceSave.Text = 'Sim';
            app.PanelAlertError = uipanel(app.UIFigure);
            app.PanelAlertError.Title = 'Erro';
            app.PanelAlertError.Units = 'pixels';
            app.okError = uibutton(app.PanelAlertError, 'push');
            app.okError.ButtonPushedFcn = createCallbackFcn(app, @okErrorButtonPushed);
            app.okError.Text = 'OK';
            app.Label58 = uilabel(app.PanelAlertError);
            app.Label58.Text = 'ERRORRR';
            app.PanelReulst = uipanel(app.UIFigure);
            app.PanelReulst.Title = 'Resultados';
            app.PanelReulst.Units = 'pixels';
            app.Button = uibutton(app.PanelReulst, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'OK';
            app.Label92 = uilabel(app.PanelReulst);
            app.Label92.Text = 'Precisão de Teste:';
            app.Label93 = uilabel(app.PanelReulst);
            app.Label93.Text = 'Precisão Global:';
            app.Label94 = uilabel(app.PanelReulst);
            app.Label95 = uilabel(app.PanelReulst);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end